classdef q4dsp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ButtonGroup_2          matlab.ui.container.ButtonGroup
        frequencyButton        matlab.ui.control.RadioButton
        timeButton             matlab.ui.control.RadioButton
        EchoParametersPanel    matlab.ui.container.Panel
        EchoButton             matlab.ui.control.Button
        aEditField             matlab.ui.control.NumericEditField
        aEditFieldLabel        matlab.ui.control.Label
        MEditField             matlab.ui.control.NumericEditField
        MEditFieldLabel        matlab.ui.control.Label
        NEditField             matlab.ui.control.NumericEditField
        NEditFieldLabel        matlab.ui.control.Label
        AudiosignalPanel       matlab.ui.container.Panel
        ButtonGroup            matlab.ui.container.ButtonGroup
        FrequencyDomainButton  matlab.ui.control.RadioButton
        TimeDomainButton       matlab.ui.control.RadioButton
        UIAxes                 matlab.ui.control.UIAxes
        saveochedvoicePanel    matlab.ui.container.Panel
        saveButton             matlab.ui.control.Button
        playAudiofilePanel     matlab.ui.container.Panel
        EchoAudioButton        matlab.ui.control.Button
        inputAudioButton       matlab.ui.control.StateButton
        iopwnAudiofilePanel    matlab.ui.container.Panel
        openAudiofileButton    matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       audioY=0
       audioFs=0
       audioX=0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openAudiofileButton
        function openAudiofileButtonPushed(app, event)
          [file,path] = uigetfile('*.wav');
          disp(path);
          [y,Fs] = audioread(fullfile(path,file));
          app.audioY=y;
          app.audioFs=Fs;
          %sound(y,Fs);
        end

        % Callback function
        function EchoButtonPushed(app, event)
              

        end

        % Value changed function: inputAudioButton
        function inputAudioButtonValueChanged(app, event)
           sound(app.audioY,app.audioFs)
        end

        % Button pushed function: EchoAudioButton
        function EchoAudioButtonPushed(app, event)
           sound(app.audioX,app.audioFs)
        end

        % Callback function
        function echoButtonValueChanged(app, event)

        end

        % Callback function
        function EchoButtonPushed2(app, event)
                N=app.NEditField.Value;
                M=app.MEditField.Value;
                a=app.aEditField.Value;
                x(1:M*N) = app.audioY(1:M*N);  
                for k=1:N
                  for i=(k*M)+1:length(app.audioY)  
                    x(i) = app.audioY(i) + (a^k)*app.audioY(i-(k*M));  
                   end 
                end
                app.audioX=x;
                  %sound(x,app.audioFs)           
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
           [file,path] = uiputfile('*.wav');
            filename = fullfile(path, file);
            audiowrite(filename, app.audioX, app.audioFs);

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            time= 0:1/app.audioFs:length(app.audioY)/app.audioFs-1/app.audioFs;
            if app.TimeDomainButton.Value
                plot(app.UIAxes,time,app.audioY);xlabel(app.UIAxes,'Time(s)');ylabel(app.UIAxes,'Amplitude')
            else
                yfft=abs(fft(app.audioY));
                yfft=fftshift(yfft);
                fm=linspace(-app.audioFs/2,app.audioFs/2,length(yfft));
                plot(app.UIAxes,fm,yfft);xlabel(app.UIAxes,'Frequency(HZ)');ylabel(app.UIAxes,'magnitude')
            end

             
        end

        % Callback function
        function echoButtonPushed(app, event)
             N=app.NEditField.Value;
                M=app.MEditField.Value;
                a=app.aEditField.Value;
                x(1:M*N) = app.audioY(1:M*N);  
                for k=1:N
                  for i=(k*M)+1:length(app.audioY)  
                    x(i) = app.audioY(i) + (a^k)*app.audioY(i-(k*M));  
                   end 
                end
                app.audioX=x;
                  %sound(x,app.audioFs)   
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
          
            
        end

        % Button pushed function: EchoButton
        function EchoButtonPushed3(app, event)
              N=app.NEditField.Value;
                M=app.MEditField.Value;
                a=app.aEditField.Value;
                x(1:M*N) = app.audioY(1:M*N);  
                for k=1:N
                  for i=(k*M)+1:length(app.audioY)  
                    x(i) = app.audioY(i) + (a^k)*app.audioY(i-(k*M));  
                   end 
                end
                app.audioX=x;
                  %sound(x,app.audioFs) 
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged2(app, event)
            %selectedButton = app.ButtonGroup_2.SelectedObject;
             time= 0:1/app.audioFs:length(app.audioX)/app.audioFs-1/app.audioFs;
            if app.timeButton.Value
                plot(app.UIAxes2,time,app.audioX);xlabel(app.UIAxes2,'Time(s)');ylabel(app.UIAxes2,'Amplitude')
            else 
                xfft=abs(fft(app.audioX));
                xfft=fftshift(xfft);
                fm=linspace(-app.audioFs/2,app.audioFs/2,length(xfft));
                plot(app.UIAxes2,fm,xfft);xlabel(app.UIAxes2,'Frequency(HZ)');ylabel(app.UIAxes2,'magnitude')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Audio signal with Echo')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.iopwnAudiofilePanel = uipanel(app.UIFigure);
            app.iopwnAudiofilePanel.Title = 'i=opwn Audio file ';
            app.openAudiofileButton = uibutton(app.iopwnAudiofilePanel, 'push');
            app.openAudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @openAudiofileButtonPushed, true);
            app.openAudiofileButton.Text = 'open Audio file';
            app.playAudiofilePanel = uipanel(app.UIFigure);
            app.playAudiofilePanel.Title = 'play Audio file';
            app.inputAudioButton = uibutton(app.playAudiofilePanel, 'state');
            app.inputAudioButton.ValueChangedFcn = createCallbackFcn(app, @inputAudioButtonValueChanged, true);
            app.inputAudioButton.Text = 'input Audio';
            app.EchoAudioButton = uibutton(app.playAudiofilePanel, 'push');
            app.EchoAudioButton.ButtonPushedFcn = createCallbackFcn(app, @EchoAudioButtonPushed, true);
            app.EchoAudioButton.Text = 'Echo Audio';
            app.saveochedvoicePanel = uipanel(app.UIFigure);
            app.saveochedvoicePanel.Title = 'save oched voice';
            app.saveButton = uibutton(app.saveochedvoicePanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.AudiosignalPanel = uipanel(app.UIFigure);
            app.AudiosignalPanel.Title = 'Audio signal';
            app.UIAxes = uiaxes(app.AudiosignalPanel);
            title(app.UIAxes, 'Audio signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ButtonGroup = uibuttongroup(app.AudiosignalPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.TimeDomainButton = uiradiobutton(app.ButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.TimeDomainButton.Value = true;
            app.FrequencyDomainButton = uiradiobutton(app.ButtonGroup);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.EchoParametersPanel = uipanel(app.UIFigure);
            app.EchoParametersPanel.Title = 'Echo  Parameters';
            app.NEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.NEditFieldLabel.Text = 'N     ';
            app.NEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.MEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.MEditFieldLabel.Text = 'M       ';
            app.MEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.aEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.aEditFieldLabel.Text = 'a     ';
            app.aEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.EchoButton = uibutton(app.EchoParametersPanel, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoButtonPushed3, true);
            app.EchoButton.Text = 'Echo';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged2, true);
            app.timeButton = uiradiobutton(app.ButtonGroup_2);
            app.timeButton.Text = 'time';
            app.timeButton.Value = true;
            app.frequencyButton = uiradiobutton(app.ButtonGroup_2);
            app.frequencyButton.Text = 'frequency';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = q4dsp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end