classdef dsp0_matlab_q2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        filtercut_offFrequencyPanel  matlab.ui.container.Panel
        WEditField                   matlab.ui.control.NumericEditField
        WEditFieldLabel              matlab.ui.control.Label
        downsamplingPanel            matlab.ui.container.Panel
        MEditField                   matlab.ui.control.NumericEditField
        MEditFieldLabel              matlab.ui.control.Label
        upsamplingPanel              matlab.ui.container.Panel
        LEditField                   matlab.ui.control.NumericEditField
        LEditFieldLabel              matlab.ui.control.Label
        sinc2N2Panel                 matlab.ui.container.Panel
        NEditField                   matlab.ui.control.NumericEditField
        NEditFieldLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       x
       y
       y2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            N=3;
            M=1;
            L=1;
            W=0;
            app.NEditField.Value=3;
            app.MEditField.Value=1;
            app.LEditField.Value=1;
            app.WEditField.Value=0;
            n=0:299;
            m=300;
            app.x=sinc((n-(m/2))/(2*N)).^2;
           app. y=upsample(app.x,L);
            app.y2=downsample(app.y,M);
            if W>0
              y1 = fir1(150,W,'low');
              y3=conv(y1,app.y2);
              
           else
              y3=app.y2;
            end
            [h,f] = freqz(y3,1,-3*pi:pi/1000:3*pi);
            plot(app.UIAxes,f/pi,abs(h))
            xlabel(app.UIAxes,'Normalized frequency')
            ylabel(app.UIAxes,'Magnitude')
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
          N = app.NEditField.Value;
          m=300;
          n=0:299;
          app.x=sinc((n-(m/2))/(2*N)).^2;
          [h,f] = freqz(app.x,1,-3*pi:pi/1000:3*pi);
          plot(app.UIAxes,f/pi,abs(h))
           xlabel(app.UIAxes,'Normalized frequency')
           ylabel(app.UIAxes,'Magnitude')
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
           L = app.LEditField.Value;
           W= app.WEditField.Value;
           if W>0
            y1 = fir1(150,W,'low');
            y3=conv(y1,app.y2);
           else
               y3=app.x;
           end
           app.y=upsample(y3,L);
           [h,f] = freqz(app.y,1,-3*pi:pi/1000:3*pi);
           plot(app.UIAxes,f/pi,abs(h))
           xlabel(app.UIAxes,'Normalized frequency')
           ylabel(app.UIAxes,'Magnitude')
        end

        % Value changed function: WEditField
        function WEditFieldValueChanged(app, event)
             W= app.WEditField.Value;       
             y1 = fir1(150,W,'low');
             y3=conv(y1,app.y2);
            [h,f] = freqz(y3,1,-3*pi:pi/1000:3*pi);
            plot(app.UIAxes,f/pi,abs(h))
            xlabel(app.UIAxes,'Normalized frequency')
            ylabel(app.UIAxes,'Magnitude')
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
           M = app.MEditField.Value;
           app.y2=downsample(app.y,M);
           [h,f] = freqz(app.y2,1,-3*pi:pi/1000:3*pi);
           plot(app.UIAxes,f/pi,abs(h))
           xlabel(app.UIAxes,'Normalized frequency')
           ylabel(app.UIAxes,'Magnitude')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.sinc2N2Panel = uipanel(app.UIFigure);
            app.sinc2N2Panel.Title = 'sinc(/2N))^2';
            app.NEditFieldLabel = uilabel(app.sinc2N2Panel);
            app.NEditFieldLabel.Text = 'N    ';
            app.NEditField = uieditfield(app.sinc2N2Panel, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.upsamplingPanel = uipanel(app.UIFigure);
            app.upsamplingPanel.Title = 'upsampling';
            app.LEditFieldLabel = uilabel(app.upsamplingPanel);
            app.LEditFieldLabel.Text = 'L       ';
            app.LEditField = uieditfield(app.upsamplingPanel, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.downsamplingPanel = uipanel(app.UIFigure);
            app.downsamplingPanel.Title = 'downsampling';
            app.MEditFieldLabel = uilabel(app.downsamplingPanel);
            app.MEditFieldLabel.Text = 'M       ';
            app.MEditField = uieditfield(app.downsamplingPanel, 'numeric');
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.filtercut_offFrequencyPanel = uipanel(app.UIFigure);
            app.filtercut_offFrequencyPanel.Title = 'filter cut_off Frequency';
            app.WEditFieldLabel = uilabel(app.filtercut_offFrequencyPanel);
            app.WEditFieldLabel.Text = 'W       ';
            app.WEditField = uieditfield(app.filtercut_offFrequencyPanel, 'numeric');
            app.WEditField.ValueChangedFcn = createCallbackFcn(app, @WEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsp0_matlab_q2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end